classdef appForTask1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Rotate60degreesButton_2         matlab.ui.control.Button
        Rotate60degreesButton           matlab.ui.control.Button
        RotatetheoriginalimageDropDown  matlab.ui.control.DropDown
        LabeldifferentobjectsDropDown_2Label  matlab.ui.control.Label
        Rotate30degreesButton           matlab.ui.control.Button
        LabeldifferentobjectsDropDown   matlab.ui.control.DropDown
        LabelDropDownLabel              matlab.ui.control.Label
        LabelButton                     matlab.ui.control.Button
        EdgedetectionDropDown           matlab.ui.control.DropDown
        EdgedetectionDropDownLabel      matlab.ui.control.Label
        EdgedetectionButton             matlab.ui.control.Button
        RegiongrowButton                matlab.ui.control.Button
        CreateandcleanseedssetButton    matlab.ui.control.Button
        Y                               matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        X                               matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        OnepixelthinimageDropDown       matlab.ui.control.DropDown
        OnepixelthinimageDropDownLabel  matlab.ui.control.Label
        SkeletonizeButton               matlab.ui.control.Button
        SelectseedsforregiongrowingLabel  matlab.ui.control.Label
        BinarizetheimageDropDown        matlab.ui.control.DropDown
        BinarizeMethodLabel             matlab.ui.control.Label
        BinarizeButton                  matlab.ui.control.Button
        Task1AppDesignLabel             matlab.ui.control.Label
        ShoworiginalimageButton         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShoworiginalimageButton
        function LoadImgButtonPushed(app, event)
            close all;
            global original_image
            data = importdata("chromo.txt");
            original_image = decode(data);
            imshow(original_image, [],'InitialMagnification','fit','parent',app.UIAxes);

        end

        % Button pushed function: BinarizeButton
        function Binarize(app, event)
            method = app.BinarizetheimageDropDown.Value;
            global binary_image
            global best_threshold
            global original_image
                if strcmp(method,'naive')
                    binary_image = gray2binary_naive(original_image, 20); 
               
                elseif strcmp(method,'iterative')
                    binary_image = gray2binary_iterative(original_image);
                
                elseif strcmp(method,'otsu')
                    [best_threshold, binary_image] = gray2binary_otsu(original_image, 32);
                    tmp=binary_image(42:end,10:47);
                    tmp=bwmorph(tmp,'bridge');
                    tmp=bwmorph(tmp,'fill');
                    binary_image(42:end,10:47)=tmp;

                elseif strcmp(method,'kittler')
                    binary_image = gray2binary_kittler(original_image);

                elseif strcmp(method,'bernsen')
                    binary_image = gray2binary_bernsen(original_image, 7, 10, 20);

                elseif strcmp(method,'niblack')
                    binary_image = gray2binary_niblack(original_image, 7, 0.01);               
                
                end
     
           imshow(binary_image, [],'InitialMagnification','fit','parent',app.UIAxes);     
        end

        % Callback function
        function noise_filter(app, event)
            method = app.FilterMethodDropDown.Value;
            global original_image
            global img_afterFilter
                if strcmp(method,'median')
                    img_afterFilter = medianFilter(original_image, 3);
                elseif strcmp(method,'gaussian')
                    img_afterFilter = gaussianFilter(original_image, 5, 0.5);
                end
            imshow(img_afterFilter, [],'InitialMagnification','fit','parent',app.UIAxes);     
        end

        % Button pushed function: SkeletonizeButton
        function Skeletonize(app, event)
            method = app.OnepixelthinimageDropDown.Value;
            global binary_image
            global thin_image
            if strcmp(method,'hilditch')
                thin_image = thin_hilditch(binary_image);
            elseif strcmp(method,'zhangSuen')
                thin_image = thin_zhangSuen(binary_image);
            elseif strcmp(method,'lookForTable')
                thin_image = thin_lookForTable(binary_image);
            elseif strcmp(method,'rosenfeld')
                thin_image = thin_rosenfeld(binary_image);
            end
            imshow(thin_image, [],'InitialMagnification','fit','parent',app.UIAxes);     
        end

        % Callback function
        function SelectSeeds(app, event)
            value = app.X.Value;
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function WindowButtonMotionFcn(app, event)

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            global seeds
            currPt = app.UIAxes.CurrentPoint;
            xp = currPt(1,1);
            yp = currPt(1,2);
            
            if xp < app.UIAxes.XLim(1,1)||...
                    xp > app.UIAxes.XLim(1,2)||...
                    yp < app.UIAxes.YLim(1,1)||...
                    yp > app.UIAxes.YLim(1,2)
               
                app.X.Value = 0;
                app.Y.Value = 0;
               
                return
               
            end
            % 当鼠标离开坐标区时，X,Y编辑框显示为0
            app.X.Value = xp;
            app.Y.Value = yp;
            seeds = [seeds; [round(yp),round(xp)]];
        end

        % Button pushed function: CreateandcleanseedssetButton
        function cleanSeeds(app, event)
            global seeds
            seeds = [];
        end

        % Button pushed function: RegiongrowButton
        function regionGrow(app, event)
            global seeds
            global binary_image
            binary_image = rsg_process(binary_image,seeds);
            imshow(binary_image, [],'InitialMagnification','fit','parent',app.UIAxes);     
        end

        % Button pushed function: EdgedetectionButton
        function edge_detection(app, event)
            method = app.EdgedetectionDropDown.Value;
            global binary_image
            global outline_image
            if strcmp(method,'roberts')
                outline_image = detector_roberts(binary_image);
            elseif strcmp(method,'sobel')
                sobel_threshold = 3.7;
                outline_image = detector_sobel(binary_image, sobel_threshold);
            elseif strcmp(method,'prewitt')
                prewitt_threshold = 2.8;
                outline_image = detector_prewitt(binary_image, prewitt_threshold);
            elseif strcmp(method,'canny')
                outline_image = edge(binary_image, 'canny', 0.5);
            elseif strcmp(method,'laplace')
                laplace_threshold = 2.8;
                outline_image = detector_laplace(binary_image * 31, laplace_threshold);
            end
            imshow(outline_image, [],'InitialMagnification','fit','parent',app.UIAxes);     
        end

        % Button pushed function: LabelButton
        function Label(app, event)
            method = app.LabeldifferentobjectsDropDown.Value;
            global binary_image
            global label_img
            if strcmp(method,'classical')
                label_matrix = label_classical(binary_image);
            elseif strcmp(method,'region grow')
                label_matrix = label_rsg(binary_image);
            end
            label_img = label2rgb(label_matrix, 'jet', 'w', 'shuffle');
            imshow(label_img, [],'InitialMagnification','fit','parent',app.UIAxes);     
        end

        % Button pushed function: Rotate30degreesButton
        function rotate_30(app, event)
            method = app.RotatetheoriginalimageDropDown.Value;
            global original_image
            global rotated_image
            if strcmp(method,'forwardMapping')
                rotated_image = rotate_forwardMapping(original_image, 30);
            elseif strcmp(method,'nearest neighbor interpolation')
                [rotated_image,tmp] = rotate_backwardMapping(original_image, 30);
            elseif strcmp(method,'bilinear interpolation')
                [tmp,rotated_image] = rotate_backwardMapping(original_image, 30);
            end
            imshow(rotated_image, [],'InitialMagnification','fit','parent',app.UIAxes);     
        end

        % Button pushed function: Rotate60degreesButton
        function rotate_60(app, event)
            method = app.RotatetheoriginalimageDropDown.Value;
            global original_image
            global rotated_image
            if strcmp(method,'forwardMapping')
                rotated_image = rotate_forwardMapping(original_image, 60);
            elseif strcmp(method,'nearest neighbor interpolation')
                [rotated_image,tmp] = rotate_backwardMapping(original_image, 60);
            elseif strcmp(method,'bilinear interpolation')
                [tmp,rotated_image] = rotate_backwardMapping(original_image, 60);
            end
            imshow(rotated_image, [],'InitialMagnification','fit','parent',app.UIAxes);     
        end

        % Button pushed function: Rotate60degreesButton_2
        function rotate_90(app, event)
            method = app.RotatetheoriginalimageDropDown.Value;
            global original_image
            global rotated_image
            if strcmp(method,'forwardMapping')
                rotated_image = rotate_forwardMapping(original_image, 90);
            elseif strcmp(method,'nearest neighbor interpolation')
                [rotated_image,tmp] = rotate_backwardMapping(original_image, 90);
            elseif strcmp(method,'bilinear interpolation')
                [tmp,rotated_image] = rotate_backwardMapping(original_image, 90);
            end
            imshow(rotated_image, [],'InitialMagnification','fit','parent',app.UIAxes);     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ShoworiginalimageButton = uibutton(app.UIFigure, 'push');
            app.ShoworiginalimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImgButtonPushed, true);
            app.ShoworiginalimageButton.Text = 'Show original image';
            app.Task1AppDesignLabel = uilabel(app.UIFigure);
            app.Task1AppDesignLabel.Text = 'Task 1 App Design';
            app.BinarizeButton = uibutton(app.UIFigure, 'push');
            app.BinarizeButton.ButtonPushedFcn = createCallbackFcn(app, @Binarize, true);
            app.BinarizeButton.Text = 'Binarize';
            app.BinarizeMethodLabel = uilabel(app.UIFigure);
            app.BinarizeMethodLabel.Text = 'Binarize the image';
            app.BinarizetheimageDropDown = uidropdown(app.UIFigure);
            app.BinarizetheimageDropDown.Items = {'naive', 'iterative', 'kittler', 'bernsen', 'niblack', 'otsu'};
            app.BinarizetheimageDropDown.Value = 'naive';
            app.SelectseedsforregiongrowingLabel = uilabel(app.UIFigure);
            app.SelectseedsforregiongrowingLabel.Text = 'Select seeds for region growing';
            app.SkeletonizeButton = uibutton(app.UIFigure, 'push');
            app.SkeletonizeButton.ButtonPushedFcn = createCallbackFcn(app, @Skeletonize, true);
            app.SkeletonizeButton.Text = 'Skeletonize';
            app.OnepixelthinimageDropDownLabel = uilabel(app.UIFigure);
            app.OnepixelthinimageDropDownLabel.Text = 'One-pixel thin image';
            app.OnepixelthinimageDropDown = uidropdown(app.UIFigure);
            app.OnepixelthinimageDropDown.Items = {'hilditch', 'zhangSuen', 'lookForTable', 'rosenfeld'};
            app.OnepixelthinimageDropDown.Value = 'hilditch';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.X = uieditfield(app.UIFigure, 'numeric');
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.Y = uieditfield(app.UIFigure, 'numeric');
            app.CreateandcleanseedssetButton = uibutton(app.UIFigure, 'push');
            app.CreateandcleanseedssetButton.ButtonPushedFcn = createCallbackFcn(app, @cleanSeeds, true);
            app.CreateandcleanseedssetButton.Text = 'Create and clean seeds set';
            app.RegiongrowButton = uibutton(app.UIFigure, 'push');
            app.RegiongrowButton.ButtonPushedFcn = createCallbackFcn(app, @regionGrow, true);
            app.RegiongrowButton.Text = 'Region grow';
            app.EdgedetectionButton = uibutton(app.UIFigure, 'push');
            app.EdgedetectionButton.ButtonPushedFcn = createCallbackFcn(app, @edge_detection, true);
            app.EdgedetectionButton.Text = 'Edge detection';
            app.EdgedetectionDropDownLabel = uilabel(app.UIFigure);
            app.EdgedetectionDropDownLabel.Text = 'Edge detection';
            app.EdgedetectionDropDown = uidropdown(app.UIFigure);
            app.EdgedetectionDropDown.Items = {'roberts', 'sobel', 'prewitt', 'canny', 'laplace'};
            app.EdgedetectionDropDown.Value = 'roberts';
            app.LabelButton = uibutton(app.UIFigure, 'push');
            app.LabelButton.ButtonPushedFcn = createCallbackFcn(app, @Label, true);
            app.LabelButton.Text = 'Label';
            app.LabelDropDownLabel = uilabel(app.UIFigure);
            app.LabelDropDownLabel.Text = 'Label different objects ';
            app.LabeldifferentobjectsDropDown = uidropdown(app.UIFigure);
            app.LabeldifferentobjectsDropDown.Items = {'classical', 'region grow'};
            app.LabeldifferentobjectsDropDown.Value = 'classical';
            app.Rotate30degreesButton = uibutton(app.UIFigure, 'push');
            app.Rotate30degreesButton.ButtonPushedFcn = createCallbackFcn(app, @rotate_30, true);
            app.Rotate30degreesButton.Text = 'Rotate 30 degrees';
            app.LabeldifferentobjectsDropDown_2Label = uilabel(app.UIFigure);
            app.LabeldifferentobjectsDropDown_2Label.Text = 'Rotate the original image';
            app.RotatetheoriginalimageDropDown = uidropdown(app.UIFigure);
            app.RotatetheoriginalimageDropDown.Items = {'forwardMapping', 'nearest neighbor interpolation', 'bilinear interpolation'};
            app.RotatetheoriginalimageDropDown.Value = 'forwardMapping';
            app.Rotate60degreesButton = uibutton(app.UIFigure, 'push');
            app.Rotate60degreesButton.ButtonPushedFcn = createCallbackFcn(app, @rotate_60, true);
            app.Rotate60degreesButton.Text = 'Rotate 60 degrees';
            app.Rotate60degreesButton_2 = uibutton(app.UIFigure, 'push');
            app.Rotate60degreesButton_2.ButtonPushedFcn = createCallbackFcn(app, @rotate_90, true);
            app.Rotate60degreesButton_2.Text = 'Rotate 60 degrees';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appForTask1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end